classdef AdjustLabelMask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        ControlsTab              matlab.ui.container.Tab
        RedoButton               matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        HistorylengthEditField   matlab.ui.control.NumericEditField
        HistorylengthLabel       matlab.ui.control.Label
        UsewithcautionLabel      matlab.ui.control.Label
        UndoButton               matlab.ui.control.Button
        SpotsizeSpinner          matlab.ui.control.Spinner
        SpotsizeSpinnerLabel     matlab.ui.control.Label
        Instructions             matlab.ui.control.Label
        LoadButton               matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        DrawmodeButton           matlab.ui.control.StateButton
        FillmodeButton           matlab.ui.control.StateButton
        InstructionsLabel        matlab.ui.control.Label
        DisplayTab               matlab.ui.container.Tab
        RandomizeButton          matlab.ui.control.Button
        MaskalphaEditField       matlab.ui.control.NumericEditField
        MaskalphaEditFieldLabel  matlab.ui.control.Label
        LabelColors              matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        AddLabelButton           matlab.ui.control.Button
        FinalizeTab              matlab.ui.container.Tab
        SavetestButton           matlab.ui.control.Button
        SavetrainButton          matlab.ui.control.Button
        MainImageAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        labim % Description
        MoveScreen % Description
        CP % Description
        cmap % Description
        labs % Description
        im % Description
        lmin % Description
        lmax % Description
        plabs % Description
        enum % Description
    end
    
    methods (Access = private)
        
        function genLabelList(app)
            app.MainImageAxes.CLim = [app.lmin app.lmax];
            
            clabs = app.UITable.Data;
            if isempty(clabs)
                clabs = ["0" sprintf("%0.2f",rand(1)) ...
                    sprintf("%0.2f",rand(1)) sprintf("%0.2f",rand(1))];
            end
            newlabs = [app.lmin:app.lmax]';
            newdata = [];
            newdata = strings([numel(newlabs),4]);
            for ind = 1:numel(newlabs)
                
                i = newlabs(ind);
                existlab = clabs(:,1)==sprintf("%d",i);
                if any(existlab)
                    newdata(ind,:) = clabs(existlab,:);
                else
                    newdata(ind,:) = [sprintf("%d",i) sprintf("%0.2f",rand(1)) ...
                        sprintf("%0.2f",rand(1)) sprintf("%0.2f",rand(1))];
                end
            end
            
            app.UITable.Data = newdata;
            
            app.MainImageAxes.Colormap = str2double(app.UITable.Data(:,2:end));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, im, labs)
            
            if nargin < 2
                im = 0;
            end
            if nargin < 3
                labs = zeros(size(im));
            end
            app.im = im;
            app.labs = labs;
            imshow(real2rgb(im,'gray'),'Parent',app.MainImageAxes);
            hold(app.MainImageAxes,'on');
            app.labim = imagesc(labs,'Parent',app.MainImageAxes);
            
            
            app.MaskalphaEditField.Value = 0.2;
            app.labim.AlphaData = app.MaskalphaEditField.Value;
            app.lmin = min(app.labs,[],'all');
            app.lmax = max(app.labs,[],'all');
            app.genLabelList;
            app.enum = 1;
            app.plabs = app.labs;
            app.UndoButton.Enable = false;
            app.RedoButton.Enable = false;
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = 3*event.VerticalScrollCount;
            
            xlims1 = get(app.MainImageAxes,'xlim');
            ylims1 = get(app.MainImageAxes,'ylim');
            
            app.MainImageAxes.XLim = [xlims1(1) - verticalScrollCount...
                xlims1(2) + verticalScrollCount];
            app.MainImageAxes.YLim = [ylims1(1) - verticalScrollCount...
                ylims1(2) + verticalScrollCount];
            
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            keynum = str2double(key);
            if key == "m"
                app.MoveScreen = true;
                app.CP = get(app.MainImageAxes,'CurrentPoint');
            elseif ~isnan(keynum)
                
                if app.enum>app.HistorylengthEditField.Value
                    app.enum = app.enum-1;
                    app.plabs(:,:,1) = [];
                end
                C = get(app.MainImageAxes,'CurrentPoint');
                if app.DrawmodeButton.Value
                    tempmask = zeros(size(app.labs));
                    tempmask(round(C(1,2)),round(C(1,1))) = 1;
                    tempmask = imdilate(tempmask,... 
                            strel('disk',app.SpotsizeSpinner.Value));
                    app.labs(tempmask==1) = keynum;
                    
                elseif app.FillmodeButton.Value
                    pt = sub2ind(size(app.labs),round(C(1,2)),round(C(1,1)));
                    clab = app.labs(pt);
                    CC = bwconncomp(app.labs==clab);
                    R = regionprops(CC,'PixelIdxList');
                    Rind = arrayfun(@(x) ismember(pt,x.PixelIdxList),R);
                    R = R(Rind);
                    app.labs(R.PixelIdxList) = keynum;
                end
                app.labim.CData = app.labs;
            end
                
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            keynum = str2double(key);
            if key == "m"
                app.MoveScreen = false;
            elseif ~isnan(keynum)
                app.UndoButton.Enable = true;
                app.enum = app.enum + 1;
                app.plabs(:,:,app.enum) = app.labs;
                app.RedoButton.Enable = false;
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.MoveScreen
                C = get(app.MainImageAxes,'CurrentPoint');
                dx = C(1,1)-app.CP(1,1);
                dy = C(1,2)-app.CP(1,2);
                xlims1 = get(app.MainImageAxes,'XLim');
                ylims1 = get(app.MainImageAxes,'YLim');
                app.MainImageAxes.XLim = xlims1-dx;
                app.MainImageAxes.YLim = ylims1-dy;
                app.CP = get(app.MainImageAxes,'CurrentPoint');
            end
        end

        % Callback function
        function MaskalphaEditFieldValueChanged(app, event)
            value = app.MaskalphaEditField.Value;
            app.labim.AlphaData = value;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.genLabelList;
            
        end

        % Button pushed function: SavetrainButton
        function SavetrainButtonPushed(app, event)
            fpath = uigetdir;
            mkdir(fullfile(fpath,'train'));
            mkdir(fullfile(fpath,'train','msk'));
            mkdir(fullfile(fpath,'train','img'));
            i = 1;
            l = max(app.labs,[],'all');
            fname = sprintf('im%03d_j%d.png',i,l);
            imfname = sprintf('im%03d.png',i);
            while exist(fullfile(fpath,'train','msk',fname))
                i = i+1;
                fname = sprintf('im%03d_j%d.png',i,l);
                imfname = sprintf('im%03d.png',i);
            end
            imwrite(app.labs,fullfile(fpath,'train','msk',fname));
            imwrite(app.im,fullfile(fpath,'train','img',imfname));
            
        end

        % Button pushed function: SavetestButton
        function SavetestButtonPushed(app, event)
            fpath = uigetdir;
            mkdir(fullfile(fpath,'test'));
            mkdir(fullfile(fpath,'test','msk'));
            mkdir(fullfile(fpath,'test','img'));
            i = 1;
            l = max(app.labs,[],'all');
            fname = sprintf('im%03d_j%d.png',i,l);
            imfname = sprintf('im%03d.png',i);
            while exist(fullfile(fpath,'test','msk',fname))
                i = i+1;
                fname = sprintf('im%03d_j%d.png',i,l);
                imfname = sprintf('im%03d.png',i);
            end
            imwrite(app.labs,fullfile(fpath,'test','msk',fname));
            imwrite(app.im,fullfile(fpath,'img',imfname));
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            app.MainImageAxes.Position = [260 1 position(3) position(4)];
            app.TabGroup.Position = [1 position(4)-401 260 400];
        end

        % Callback function
        function SaveButtonPushed(app, event)
            [fname, ffldr] = uiputfile ({'*.mat', 'MatLab Data Files'}, 'Choose savefile path', 'LabelMask.mat');
            im = app.im; 
            labs = app.labs;
            save (fullfile (ffldr, fname), 'im', 'labs');
        end

        % Callback function
        function LoadButtonPushed(app, event)
            [fname, ffldr] = uigetfile ({'*.mat', 'MatLab data files'}, ...
                'Select file to load', 'LabelMask.mat');
            fpath = fullfile (ffldr, fname);
            if not (exist (fpath, 'file'))
                warndlg ('selected file does not exist')
                return
            end
            data = load(fpath,'im','labs');
            app.im = data.im;
            app.labs = data.labs;
            imshow(real2rgb(app.im,'gray'),'Parent',app.MainImageAxes);
            hold(app.MainImageAxes,'on');
            app.labim = imagesc(app.labs,'Parent',app.MainImageAxes);
            app.labim.AlphaData = app.MaskalphaEditField.Value;
            app.MainImageAxes.CLim = [0 5];
            
        end

        % Button pushed function: AddLabelButton
        function AddLabelButtonPushed(app, event)
            app.lmax = app.lmax+1;
            app.genLabelList;
        end

        % Value changed function: FillmodeButton
        function FillmodeButtonValueChanged(app, event)
            value = app.FillmodeButton.Value;
            if value
                app.DrawmodeButton.Value = false;
            end
        end

        % Value changed function: DrawmodeButton
        function DrawmodeButtonValueChanged(app, event)
            value = app.DrawmodeButton.Value;
            if value
                app.FillmodeButton.Value = false;
            end
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)

            app.enum = app.enum - 1;
            app.labs = app.plabs(:,:,app.enum);
            app.labim.CData = app.labs;
            if app.enum == 1
                app.UndoButton.Enable = false;
            end
            app.RedoButton.Enable = true;
            
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            app.enum = app.enum + 1;
            app.labs = app.plabs(:,:,app.enum);
            app.labim.CData = app.labs;
            sz = size(app.plabs);
            if app.enum >= sz(3)
                app.RedoButton.Enable = false;
            end
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
            app.UITable.Data = [];
            app.genLabelList;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.MainImageAxes = uiaxes(app.UIFigure);
            app.MainImageAxes.XTick = [];
            app.MainImageAxes.YTick = [];
            app.MainImageAxes.Visible = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlsTab = uitab(app.TabGroup);
            app.ControlsTab.Title = 'Controls';
            app.InstructionsLabel = uilabel(app.ControlsTab);
            app.InstructionsLabel.Text = 'Instructions:';
            app.FillmodeButton = uibutton(app.ControlsTab, 'state');
            app.FillmodeButton.ValueChangedFcn = createCallbackFcn(app, @FillmodeButtonValueChanged, true);
            app.FillmodeButton.Text = 'Fill mode';
            app.DrawmodeButton = uibutton(app.ControlsTab, 'state');
            app.DrawmodeButton.ValueChangedFcn = createCallbackFcn(app, @DrawmodeButtonValueChanged, true);
            app.DrawmodeButton.Text = 'Draw mode';
            app.SaveButton = uibutton(app.ControlsTab, 'push');
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.ControlsTab, 'push');
            app.LoadButton.Text = 'Load';
            app.Instructions = uilabel(app.ControlsTab);
            app.Instructions.Text = {'Activate edit mode.'; 'Hold a number and draw.'; 'Hold ''m'' and drag image.'; 'Scroll to zoom.'};
            app.SpotsizeSpinnerLabel = uilabel(app.ControlsTab);
            app.SpotsizeSpinnerLabel.Text = 'Spot size';
            app.SpotsizeSpinner = uispinner(app.ControlsTab);
            app.SpotsizeSpinner.Editable = 'off';
            app.SpotsizeSpinner.Value = 3;
            app.UndoButton = uibutton(app.ControlsTab, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.UsewithcautionLabel = uilabel(app.ControlsTab);
            app.UsewithcautionLabel.Text = '* Use with caution';
            app.HistorylengthLabel = uilabel(app.ControlsTab);
            app.HistorylengthLabel.Text = 'History length:';
            app.HistorylengthEditField = uieditfield(app.ControlsTab, 'numeric');
            app.HistorylengthEditField.Value = 10;
            app.Label = uilabel(app.ControlsTab);
            app.Label.Text = '_______________________________';
            app.RedoButton = uibutton(app.ControlsTab, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'Redo';
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.AddLabelButton = uibutton(app.DisplayTab, 'push');
            app.AddLabelButton.ButtonPushedFcn = createCallbackFcn(app, @AddLabelButtonPushed, true);
            app.AddLabelButton.Text = 'Add Label';
            app.UITable = uitable(app.DisplayTab);
            app.UITable.ColumnName = {'Label'; 'R'; 'G'; 'B'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.LabelColors = uilabel(app.DisplayTab);
            app.LabelColors.Text = 'Label Colors:';
            app.MaskalphaEditFieldLabel = uilabel(app.DisplayTab);
            app.MaskalphaEditFieldLabel.Text = 'Mask alpha';
            app.MaskalphaEditField = uieditfield(app.DisplayTab, 'numeric');
            app.RandomizeButton = uibutton(app.DisplayTab, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.FinalizeTab = uitab(app.TabGroup);
            app.FinalizeTab.Title = 'Finalize';
            app.SavetrainButton = uibutton(app.FinalizeTab, 'push');
            app.SavetrainButton.ButtonPushedFcn = createCallbackFcn(app, @SavetrainButtonPushed, true);
            app.SavetrainButton.Text = 'Save train';
            app.SavetestButton = uibutton(app.FinalizeTab, 'push');
            app.SavetestButton.ButtonPushedFcn = createCallbackFcn(app, @SavetestButtonPushed, true);
            app.SavetestButton.Text = 'Save test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdjustLabelMask(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end