classdef spineLabeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        MethodDropDown                matlab.ui.control.DropDown
        MethodDropDownLabel           matlab.ui.control.Label
        SkipapprovedCheckBox          matlab.ui.control.CheckBox
        UnapproveCurrentCell          matlab.ui.control.Button
        AutosmoothCheckBox            matlab.ui.control.CheckBox
        NextUnapprovedButton          matlab.ui.control.Button
        PrevCell                      matlab.ui.control.Button
        LoadExistingButton            matlab.ui.control.Button
        ApprovedLamp                  matlab.ui.control.Lamp
        ApprovedLampLabel             matlab.ui.control.Label
        OverlayalphaEditField         matlab.ui.control.NumericEditField
        OverlayalphaEditFieldLabel    matlab.ui.control.Label
        ResetStreamlineButton         matlab.ui.control.Button
        SmoothStreamlineButton        matlab.ui.control.Button
        ExpandCropButton              matlab.ui.control.Button
        CellwidthEditField            matlab.ui.control.NumericEditField
        CellwidthEditFieldLabel       matlab.ui.control.Label
        BusyLamp                      matlab.ui.control.Lamp
        BusyLampLabel                 matlab.ui.control.Label
        RefineManuallyButton          matlab.ui.control.Button
        SmoothingScaleEditField       matlab.ui.control.NumericEditField
        SmoothingScaleEditFieldLabel  matlab.ui.control.Label
        ApproveCurrentCell            matlab.ui.control.Button
        NextCell                      matlab.ui.control.Button
        FindCellsButton               matlab.ui.control.Button
        BrightnessThresholdEditField  matlab.ui.control.NumericEditField
        BrightnessThresholdEditFieldLabel  matlab.ui.control.Label
        MinLengthpixEditField         matlab.ui.control.NumericEditField
        MinLengthpixEditFieldLabel    matlab.ui.control.Label
        MaxLengthpixEditField         matlab.ui.control.NumericEditField
        MaxLengthpixEditFieldLabel    matlab.ui.control.Label
        FindCellsParametersLabel      matlab.ui.control.Label
        SmoothnessEditField           matlab.ui.control.NumericEditField
        SmoothnessEditFieldLabel      matlab.ui.control.Label
        CurrentCellAxes               matlab.ui.control.UIAxes
        CenterPanel                   matlab.ui.container.Panel
        SpotsizeEditField             matlab.ui.control.NumericEditField
        SpotsizeEditFieldLabel        matlab.ui.control.Label
        Editmask0background1cells2gunkButton  matlab.ui.control.StateButton
        CellsPlotAxes                 matlab.ui.control.UIAxes
        ApprovedImageAxes             matlab.ui.control.UIAxes
        MainImageAxes                 matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        CID                           matlab.ui.control.Label
        CurrentIDLabel                matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        ConfirmNewCellButton          matlab.ui.control.Button
        ShowExistingCheckBox          matlab.ui.control.CheckBox
        GotoCellButton                matlab.ui.control.Button
        EnlargeApprovedCellsButton    matlab.ui.control.Button
        EnlargeFindCellsResultButton  matlab.ui.control.Button
        AddNewCellButton              matlab.ui.control.Button
        TotalNumCellsEditField        matlab.ui.control.NumericEditField
        TotalNumCellsEditFieldLabel   matlab.ui.control.Label
        NumApprovedEditField          matlab.ui.control.NumericEditField
        NumApprovedEditFieldLabel     matlab.ui.control.Label
        SaveDataButton                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Image = []; % main image
        Mask = [];  % cell mask (1 => cells)
        CellList = [];     % list generated by `findcells`
        % struct that holds current cell data
        CurrentCellData = struct ('id', nan, ...
                'crop_box', nan(1,4), ...
                'refined', nan(2,1), ...
                'init', nan (2,1), ...
                'approved', false,...
                'mask',[]);
        p0; % = drawpolyline(app.MainImageAxes,'Position',[0 0],'Visible','off',...
            %    'LineWidth',1,'Color','r'); % ROI for active cell.
        CellsPlot; % = surface([x';x'],[y';y'],[z';z'],[c';c'],...
               % 'facecol','no',...
               % 'edgecol','flat',...
               % 'linew',1,...
               % 'parent',app.ApprovedImageAxes); % Plot object for all cells.
        CellsPlot2; % Description
        
        DrawCell = false; % Flag for drawing cells as the mouse moves.
        MoveScreen = false; % Flag for moving the screen as the mouse moves.
        CP; % Place holder for mouse position.
        
        MovePoints = false; % Flag to move the closest point to the mouse as it moves.
        MovePointi; % The index for the point to move.
        MainBox = []; % Yellow box on the Approved cells plot.
        xlims; % Description
        ylims; % Description
        DrawCellBackwards = false; % Description
        Mask0; % Description
        Overlay; % Description
        CellOutline;
        MaskContour; % Description
    end
    
    methods (Access = private)
        
        function clearApprovedCells(app)
            for ci = 1:numel(app.CellList)
                app.CellList(ci).approved = false;
            end
        end
        
        function loadNewCurrentCell (app, id)
            app.CID.Text = string(id);
            idx = app.foundCellIndexForId (id);
            app.CurrentCellData = app.CellList(idx);
            app.Mask0 = app.CurrentCellData.mask;
            
            app.drawCurrentCell();
            if (app.CurrentCellData.approved)
                app.ApprovedLamp.Color = [0 1 0];
            else
                app.ApprovedLamp.Color = [1 0 0];
            end
        end
        
        function drawCurrentCell (app)
            % draw the cell image
            
            app.Overlay.AlphaData = app.Mask0*app.OverlayalphaEditField.Value;
            app.CellOutline.ZData = double(app.Mask0);
            % plot the streamline
            hold (app.CurrentCellAxes, 'on')
            
            app.CurrentCellAxes.XLim = app.MainImageAxes.XLim;
            app.CurrentCellAxes.YLim = app.MainImageAxes.YLim;
        end
        
        function setBusyLamp (app, is_busy)
            if is_busy
                app.BusyLamp.Color = [0 1 0];
            else
                app.BusyLamp.Color = [0.86 0.86 0.86];
            end
            drawnow;
        end
        
        function drawApprovedCells(app,idi)
            % show image
            % loop through found cells, only plotting approved
            idxi = app.foundCellIndexForId(idi);
            x = [];
            y = [];
            c = [];
            a = [];
            for idx = 1:length(app.CellList)
                this = app.CellList(idx);
                x = [x; this.refined(:,1)];
                y = [y; this.refined(:,2)];
                if this.approved
                    c = [c; ones(size(this.refined(:,1)))];
                else
                    c = [c; zeros(size(this.refined(:,1)))];
                end
                a = [a; ones(size(this.refined(:,1)))];
                a(end) = 0;
                
            end
            z = zeros(size(x));
            
            app.CellsPlot.XData=[x'; x'];
            app.CellsPlot.YData=[y'; y'];
            app.CellsPlot.ZData=[z'; z'];
            app.CellsPlot.CData=[c'; c'];
            app.CellsPlot.AlphaData=[a'; a'];
            
            app.CellsPlot2.XData=[x'; x'];
            app.CellsPlot2.YData=[y'; y'];
            app.CellsPlot2.ZData=[z'; z'];
            app.CellsPlot2.CData=[c'; c'];
            app.CellsPlot2.AlphaData=[a'; a'];
            if app.ShowExistingCheckBox.Value
                app.CellsPlot2.Visible = 'on';
            else
                app.CellsPlot2.Visible = 'off';
            end
            
            app.p0.Position = app.CellList(idxi).refined;
            app.p0.Visible = 'on';
            
            for i = 1:numel(app.p0.NodeChildren)-4
                app.p0.NodeChildren(i).Size = 3;
            end
            
            dx = max(app.p0.Position(:,1))-min(app.p0.Position(:,1));
            dy = max(app.p0.Position(:,2))-min(app.p0.Position(:,2));
            dr = max(dx,dy)+20;
            
            app.MainImageAxes.XLim = ...
                    [min(app.p0.Position(:,1))+dx/2-dr/2 min(app.p0.Position(:,1))+dx/2+dr/2];
            app.MainImageAxes.YLim = ...
                    [min(app.p0.Position(:,2))+dy/2-dr/2 min(app.p0.Position(:,2))+dy/2+dr/2];
            hold(app.ApprovedImageAxes,'on');
            hold(app.CellsPlotAxes,'on');
            hold(app.CurrentCellAxes,'on');
            app.MainBox.XData = app.MainImageAxes.XLim([1 1 2 2 1]);
            app.MainBox.YData = app.MainImageAxes.YLim([1 2 2 1 1]);
            app.ApprovedImageAxes.XLim = app.xlims;
            app.ApprovedImageAxes.YLim = app.ylims;
            app.CurrentCellAxes.XLim = app.MainImageAxes.XLim;
            app.CurrentCellAxes.YLim = app.MainImageAxes.YLim;
            
        end
        
        function idx = foundCellIndexForId(app, id)
            idx = find (arrayfun (@(x) x.id == id, app.CellList), 1);
        end
        
        
        function [next_id, idx] = getNextUnapproved(app)
            % get id's and cells that aren't approved
            idz = arrayfun (@(x) x.id, app.CellList);
            npr = not (arrayfun (@(x) x.approved, app.CellList));
            % figure out which cell to look at next
            next_id = find (idz > app.CurrentCellData.id & npr, 1, 'first');
            if isempty (next_id)
                next_id = min (idz(npr));
            end
            if not (isempty (next_id))
                idx = find (idz == next_id, 1, 'first');
            else
                idx = [];
            end
        end
        
        function crp = global2CropCoords (app, glb, rect)
            sze = size (imcrop (app.Image, rect));
            % how much does actual crop size vary from specified?
            dxy = sze([2 1]) - rect([3 4]);
            crp = glb - rect([1 2]) + dxy;
        end
        
        function glb = crop2GlobalCoords (app, crp, rect)
            sze = size (imcrop (app.Image, rect));
            dxy = sze([2 1]) - rect([3 4]);
            glb = crp + rect([1 2]) - dxy;
        end
        
        function data = cellDataFromStreamline (app, id, pix, mask)
            % figure out cell ends
            xse = pix([1 end], 1);
            yse = pix([1 end], 2);
            % crop image and display
            crop_box = cropBoxFromCellEnds (xse, yse, 20, true, ...
                size (app.Image));
            
            % crop label mask and display boundary
            data = struct ('id', id, ...
                'crop_box', crop_box, ...
                'init', pix, ...
                'refined', pix, ...
                'approved', false, ...
                'mask', mask);
        end
        
        function makeMask(app)
            if app.MethodDropDown.Value == "Spherocylinder"
                app.Mask0 = maskFromCellSpine(app.p0.Position,...
                    size(app.Image),app.CellwidthEditField.Value);
            elseif app.MethodDropDown.Value == "Brightness"
                app.Mask0 = maskFromCellSpine2(app.p0.Position,...
                    app.Image,app.CellwidthEditField.Value);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, mask)
            % add supplement folder to path
            
            if nargin < 3
                mask = ones(size(img));
            end
            
            fpath = mfilename ('fullpath');
            [this_fldr, ~, ~] = fileparts (fpath);
            addpath (genpath(fullfile (this_fldr, 'supp')));
            
            % input validation
            assert (length (size (img)) == 2, 'img must be 2d array')
            assert (all (size (img) == size (mask)), ...
                'input image and mask must be same size')
            if not (isa (img, 'double'))
                img = im2double (img);
            end
            if not (isa (mask, 'uint8'))
               mask = uint8 (mask);
            end
            
            app.Image = normalizeImage (img);
            app.Mask = mask;
            imshow (app.Image, 'Parent', app.ApprovedImageAxes);
            colormap (app.ApprovedImageAxes, 'gray');
            set (app.ApprovedImageAxes, 'XLimSpec', 'Tight')
            set (app.ApprovedImageAxes, 'YLimSpec', 'Tight')
            hold(app.ApprovedImageAxes, 'on');
            
            imshow (real2rgb(app.Image,'gray'),'Parent',app.MainImageAxes); 
            colormap (app.MainImageAxes, 'jet');
            set (app.MainImageAxes, 'XLimSpec', 'Tight')
            set (app.MainImageAxes, 'YLimSpec', 'Tight')
            hold(app.MainImageAxes, 'on');
            
            app.xlims = get(app.ApprovedImageAxes,'xlim');
            app.ylims = get(app.ApprovedImageAxes,'ylim');
            app.CellsPlotAxes.XLim = app.xlims;
            app.CellsPlotAxes.YLim = app.ylims;
            
            app.CellsPlotAxes.Colormap = [1 0 0; 0 1 0];
            hold(app.CellsPlotAxes, 'on');
            % draw image
%             app.drawImage (true);

            app.Mask0 = zeros(size(img));
            app.Mask0(1,:) = 1;
            app.Mask0(end,:) = 1;
            app.Mask0(:,1) = 1;
            app.Mask0(:,end) = 1;
            imshow(real2rgb(app.Image,'gray'),'Parent',app.CurrentCellAxes);
            hold(app.CurrentCellAxes,'on');
            app.Overlay = imagesc(app.CurrentCellAxes,ones(size(img)));
            app.Overlay.AlphaData = app.Mask0*app.OverlayalphaEditField.Value;
            [~,app.CellOutline] = contour(app.CurrentCellAxes,...
                app.Mask0,[-0.5 0.5 1.5], 'Color','r','LineWidth',1);
            [~,app.MaskContour] = contour(app.MainImageAxes,...
                app.Mask, [-0.5 0.5 1.5 2.5],'LineWidth',1);
            app.CurrentCellAxes.XLim = app.MainImageAxes.XLim;
            app.CurrentCellAxes.YLim = app.MainImageAxes.YLim;
            hold(app.CurrentCellAxes,'on');
            app.p0 = drawpolyline(app.MainImageAxes,'Position',[0 0],'Visible','off',...
                'LineWidth',1,'Color','r'); % ROI for active cell.
            
            app.setBusyLamp (false);

        end

        % Button pushed function: FindCellsButton
        function FindCellsButtonPushed(app, event)
            n_approved = sum (arrayfun (@(x) x.approved, app.CellList));
            % make sure user wants to proceed...
            if n_approved > 0
                answer = questdlg (...
                    "Re-finding cells will clear all progress. Proceed?", ...
                    "Restart approval?", "yes", "no", "no");
                if strcmp (answer, "no") % they don't
                    return
                end
            end

            % if we got here, we're good to go
            app.clearApprovedCells();
            
            app.setBusyLamp(true);
            % run findcells and plot answer
            cellz = findcells (app.Image, not (app.Mask == 1), ...
                app.SmoothnessEditField.Value, ...
                app.MaxLengthpixEditField.Value, ...
                app.MinLengthpixEditField.Value, ...
                app.BrightnessThresholdEditField.Value);
            % sort by x/y distance from (0,0)
            function [dist] = meanXYdist (c)
               meanpos = mean (c.pix, 1);
               dist = sqrt (sum (meanpos.^2));
            end
            ds = arrayfun (@meanXYdist, cellz);
            [~, idx] = sort (ds);
            cellz = cellz(idx);
            % assign ids to cells
            for ic = 1:numel(cellz)
                cellz(ic).id = ic;
                mask = maskFromCellSpine2(cellz(ic).pix,app.Image,...
                    app.CellwidthEditField.Value);
                cellz(ic).mask = mask;
            end
            % generate list of found cells
            app.CellList = arrayfun (...
                @(c) app.cellDataFromStreamline (c.id, c.pix, c.mask), ...
                cellz);
            % load first cell as current
            app.loadNewCurrentCell(1);
            app.TotalNumCellsEditField.Value = length (app.CellList);
            app.NumApprovedEditField.Value = 0;
            
            x = [0];
            y = [0];
            z = [0];
            c = [0];
            a = [0];
            
            app.CellsPlot = surface([x';x'],[y';y'],[z';z'],[c';c'],...
                'facecol','no',...
                'edgecol','flat',...
                'linew',1,...
                'parent',app.CellsPlotAxes); % Plot object for all cells.
            
            app.CellsPlot.AlphaData = [a';a'];
            app.CellsPlot.AlphaDataMapping = 'none';
            app.CellsPlot.EdgeAlpha = 'flat';
            
            
            app.CellsPlot2 = surface([x';x'],[y';y'],[z';z'],[c';c'],...
                'facecol','no',...
                'edgecol','flat',...
                'linew',1,...
                'parent',app.MainImageAxes); % Plot object for all cells.
            
            app.CellsPlot2.AlphaData = [a';a'];
            app.CellsPlot2.AlphaDataMapping = 'none';
            app.CellsPlot2.EdgeAlpha = 'flat';
            
            app.MainBox = plot([0 0 0 0 0], [0 0 0 0 0],'y','Parent',app.ApprovedImageAxes);
            
            hold(app.CellsPlotAxes,'on');
            app.drawApprovedCells(1);
            app.setBusyLamp(false);
        end

        % Button pushed function: RefineManuallyButton
        function RefineManuallyButtonPushed(app, event)
            if isnan (app.CurrentCellData.id)
                warndlg ('no cell to refine')
                return
            end
            % show big image to do adjustment in
            fig = figure(1);
            axes (fig.CurrentAxes, 'Units', 'normalize', 'Position', [0 0 1 1])
            imshow (imcrop (app.Image, app.CurrentCellData.crop_box));
            fig.WindowState = 'maximized';
            % draw polygon and wait for user to double-click to confirm
            skip = app.CellwidthEditField.Value;
            if (skip > 0)
                strlne = app.CurrentCellData.refined(1:skip:end,:);
            else
                strlne = app.CurrentCellData.refined;
            end
            strlne = app.global2CropCoords (strlne, app.CurrentCellData.crop_box);
            if (app.AutosmoothCheckBox.Value)
                glbMeanPos = @(c) mean (c.refined);
                % plot nearby approved cell contours
                apr = app.CellList(arrayfun (@(x) x.approved, app.CellList));
                if not (isempty (apr))
                    glb_muz = arrayfun (glbMeanPos, apr, 'UniformOutput', false);
                    mu = glbMeanPos (app.CurrentCellData);
                    dist = cellfun (@(g) sqrt (sum ((g-mu).^2)), glb_muz);
                    to_plot = apr(dist < 300 & dist > 1);
                    hold (fig.CurrentAxes, 'on')
                    for pii = 1:length(to_plot)
                        coords = app.global2CropCoords (to_plot(pii).refined, ...
                            app.CurrentCellData.crop_box);
                        plot (fig.CurrentAxes, coords(:,1), coords(:,2), 'r')
                    end
                    hold (fig.CurrentAxes, 'off')
                end
            end
            h = drawpolyline (fig.CurrentAxes, 'Position', strlne);
            function clickCallback (~, evt)
                if strcmp (evt.SelectionType, 'double')
                    uiresume;
                end
            end
            function [pos] = customWait (hROI)
                list = addlistener (hROI, 'ROIClicked', @clickCallback);
                uiwait;
                delete (list);
                try, pos = hROI.Position;  % throws if user x'd out window
                catch, pos = [];
                end
            end
            roi = customWait (h);
            if ~isempty (roi)
                % convert back out to global
                app.CurrentCellData.refined = app.crop2GlobalCoords (...
                    roi, app.CurrentCellData.crop_box);
            end
            app.drawCurrentCell();
            try, close (fig);  % try/catch is for if already deleted
            catch, end
        end

        % Button pushed function: ApproveCurrentCell
        function ApproveCurrentCellButtonPushed(app, event)
            % add to list of approved cells
            app.CurrentCellData.approved = true;
            app.CurrentCellData.refined = app.p0.Position;
            app.CurrentCellData.mask = app.Mask0;
            curr_idx = find (arrayfun (@(x) x.id, app.CellList) == app.CurrentCellData.id, 1);
            app.CellList(curr_idx) = app.CurrentCellData;
            
            if app.SkipapprovedCheckBox.Value
                [id, ~] = app.getNextUnapproved();
            else
                if curr_idx == numel(app.CellList)
                    curr_idx = 0;
                end
                id = app.CellList(curr_idx+1).id;
            end
            
            if isempty(id)
                id = app.CurrentCellData.id;
            end

            
            app.loadNewCurrentCell (id);
            n_approved = sum (arrayfun (@(x) x.approved, app.CellList));
%             if n_approved > 1
            app.drawApprovedCells (app.CurrentCellData.id);
%             else
%                 app.drawApprovedCells ();
%             end
            % increment approved counter
            app.NumApprovedEditField.Value = n_approved;
            drawnow;
        end

        % Button pushed function: NextCell
        function NextCellButtonPushed(app, event)
            
            idz = arrayfun (@(x) x.id, app.CellList);
            idx = find (idz > app.CurrentCellData.id, 1, 'first');
            if isempty (idx)
                warndlg ('No next cell, at end of list');
                return
            end
            app.loadNewCurrentCell (idz(idx));
            app.drawApprovedCells(idz(idx));

        end

        % Button pushed function: ExpandCropButton
        function ExpandCropButtonPushed(app, event)
            [y_sze, x_sze] = size (app.Image);
            pad = app.OverlayalphaEditField.Value;
            curr_box = app.CurrentCellData.crop_box;
            new_box = curr_box;
            new_box(1) = max (curr_box(1)-pad, 0);
            new_box(2) = max (curr_box(2)-pad, 0);
            actual_pads = curr_box(1:2) - new_box(1:2);
            cand_wid = curr_box(3) + actual_pads(1)*2;
            cand_hgt = curr_box(4) + actual_pads(2)*2;
            if (curr_box(1) + cand_wid > x_sze)
                new_box(3) = x_sze - curr_box(1);
            else
                new_box(3) = cand_wid;
            end
            if (curr_box(2) + cand_hgt > y_sze)
                new_box(4) = y_sze - curr_box(2);
            else
                new_box(4) = cand_hgt;
            end
            app.CurrentCellData.crop_box = new_box;
            app.drawCurrentCell();
        end

        % Button pushed function: SmoothStreamlineButton
        function SmoothStreamlineButtonPushed(app, event)
            if isnan (app.CurrentCellData.id)
                warndlg ('no cell contour to smooth')
                return
            end
            
            if app.SmoothingScaleEditField.Value == 0
                return
            end
            app.setBusyLamp (true);
            
            x = app.p0.Position(:,1);
            y = app.p0.Position(:,2);
            n = app.SmoothingScaleEditField.Value;
            
            x1 = movmean(x,n,'Endpoints','discard');
            y1 = movmean(y,n,'Endpoints','discard');
            
            x1 = [x(1:(numel(x)-numel(x1))/2); x1; x(end-(numel(x)-numel(x1))/2+1:end)];
            y1 = [y(1:(numel(y)-numel(y1))/2); y1; y(end-(numel(y)-numel(y1))/2+1:end)];
    
            smth = [x1 y1];
            
            app.p0.Position = smth;
            for i = 1:numel(app.p0.NodeChildren)-4
                app.p0.NodeChildren(i).Size = 3;
            end
            
            app.Mask0 = maskFromCellSpine2(app.p0.Position,...
                app.Image,app.CellwidthEditField.Value);
            app.Overlay.AlphaData = app.Mask0*app.OverlayalphaEditField.Value;
            app.CellOutline.ZData = double(app.Mask0);
            app.setBusyLamp (false);
        end

        % Button pushed function: ResetStreamlineButton
        function ResetStreamlineButtonPushed(app, event)
            app.CurrentCellData.refined = app.CurrentCellData.init;
            app.drawCurrentCell();
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            [fname, ffldr] = uiputfile ({'*.mat', 'MatLab Data Files'}, 'Choose savefile path', 'cellmask.mat');
            Image = app.Image; 
            Mask = app.Mask;
            cellList = app.CellList;
            save (fullfile (ffldr, fname), 'Image', 'Mask', 'cellList');
        end

        % Button pushed function: PrevCell
        function PrevCellButtonPushed(app, event)
            idz = arrayfun (@(x) x.id, app.CellList);
            idx = find (idz < app.CurrentCellData.id, 1, 'last');
            if isempty (idx)
                warndlg ('no previous cell, at start of list');
                return
            end
            app.loadNewCurrentCell (idz(idx));
        end

        % Button pushed function: NextUnapprovedButton
        function NextUnapprovedButtonPushed(app, event)
            [next_id, ~] = app.getNextUnapproved();
            app.loadNewCurrentCell (next_id);
        end

        % Button pushed function: LoadExistingButton
        function LoadExistingButtonPushed(app, event)
            [fname, ffldr] = uigetfile ({'*.mat', 'MatLab data files'}, ...
                'Select file to load', 'cellmask.mat');
            fpath = fullfile (ffldr, fname);
            if not (exist (fpath, 'file'))
                warndlg ('selected file does not exist')
                return
            end
            app.setBusyLamp(true);
            answer = questdlg ('Keep current image or load from existing?', ...
                'Default Parameters', 'current', 'existing', 'current');
            props = load (fpath);
            % update properties
            if strcmp (answer, 'existing')
                app.Image = props.Image;
            end
            
            x = 0;
            y = 0;
            z = 0;
            c = 0;
            a = 0;
            
            app.CellsPlot = surface([x';x'],[y';y'],[z';z'],[c';c'],...
                'facecol','no',...
                'edgecol','flat',...
                'linew',1,...
                'parent',app.CellsPlotAxes); % Plot object for all cells.
            
            app.CellsPlot.AlphaData = [a';a'];
            app.CellsPlot.AlphaDataMapping = 'none';
            app.CellsPlot.EdgeAlpha = 'flat';
            
            app.CellsPlot2 = surface([x';x'],[y';y'],[z';z'],[c';c'],...
                'facecol','no',...
                'edgecol','flat',...
                'linew',1,...
                'parent',app.MainImageAxes); % Plot object for all cells.
            
            app.CellsPlot2.AlphaData = [a';a'];
            app.CellsPlot2.AlphaDataMapping = 'none';
            app.CellsPlot2.EdgeAlpha = 'flat';
            
            app.MainBox = plot([0 0 0 0 0], [0 0 0 0 0],'y','Parent',app.CellsPlotAxes);
            
            app.Mask = props.Mask;
            app.CellList = props.cellList;
            for ic = 1:numel(app.CellList)
                if ~isfield(app.CellList(ic),'mask') || ...
                        isempty(app.CellList(ic).mask)
                    try
                        app.CellList(ic).mask = ...
                            maskFromCellSpine2(app.CellList(ic).refined,...
                            app.Image,app.CellwidthEditField.Value);
                    catch
                        app.CellList(ic).mask = ...
                            maskFromCellSpine2(app.CellList(ic).init,...
                            app.Image,app.CellwidthEditField.Value);
                    end
                end
            end

            app.Mask0 = app.CellList(1).mask;
            
            % find min unapproved cell id and set as current
            apr = arrayfun (@(x) x.approved, app.CellList);
%             npr_idx = find (not (apr), 1, 'first');
%             if isempty (npr_idx)
%                 warndlg ('didnt find an unapproved cell')
%                 npr_idx = 1;
%             end
            app.loadNewCurrentCell (1);
            % update counters
            app.TotalNumCellsEditField.Value = length (apr);
            app.NumApprovedEditField.Value = sum (apr);
            app.drawCurrentCell();
            app.drawApprovedCells(1);
            app.setBusyLamp(false);
        end

        % Button pushed function: AddNewCellButton
        function AddNewCellButtonPushed(app, event)
            
            app.p0.Visible = 'off';

        end

        % Button pushed function: EnlargeFindCellsResultButton
        function EnlargeFindCellsResultButtonPushed(app, event)
            % show results of findcells
            imshow (app.Image)
            hold ('on')
            for ic = 1:length(app.CellList)
                A = app.CellList(ic).init;
                plot (A(:,1), A(:,2))
            end
            hold ('off')
        end

        % Button pushed function: EnlargeApprovedCellsButton
        function EnlargeApprovedCellsButtonPushed(app, event)
            % show approved cell streamlines
            imshow (app.Image)
            hold ('on')
            for ic = 1:length(app.CellList)
                this = app.CellList(ic);
                if (this.approved)
                    plot (this.refined(:,1), this.refined(:,2))
                end
            end
            hold ('off')
        end

        % Button pushed function: UnapproveCurrentCell
        function UnapproveCurrentCellButtonPushed(app, event)
            % add to list of approved cells
            app.CurrentCellData.approved = false;
            curr_idx = find (arrayfun (@(x) x.id, app.CellList) == app.CurrentCellData.id, 1);
            app.CellList(curr_idx) = app.CurrentCellData;
            [id, ~] = app.getNextUnapproved();
            app.loadNewCurrentCell (id);
            n_approved = sum (arrayfun (@(x) x.approved, app.CellList));
            if n_approved > 1
                app.drawApprovedCells (curr_idx);
            else
                app.drawApprovedCells (1);
            end
            % increment approved counter
            app.NumApprovedEditField.Value = n_approved;
            drawnow;
        end

        % Button pushed function: GotoCellButton
        function GotoCellButtonPushed(app, event)
            % get approved cells
            apr = app.CellList(arrayfun (@(x) x.approved, app.CellList));
            if isempty (apr)
                warndlg ('no approved cells to go to');
                return
            end
            % show approved cell contours
            fig = figure(1);
            imshow (app.Image);
            hold ('on')
            for ic = 1:length(apr)
                plot (apr(ic).refined(:,1), apr(ic).refined(:,2))
            end
            hold ('off')
            % get user input
            [xm, ym] = ginput (1);
            click_loc = [xm, ym];
            % compute mean position of contour for each cell
            mean_pos = arrayfun (@(a) mean (a.refined), apr, ...
                'UniformOutput', false);
            % "correct" cell is the closest one to mouse click
            dist_from_click = cellfun (@(a) sqrt (sum ((a-click_loc).^2)), ...
                mean_pos);
            apr_idx = find (dist_from_click == min (dist_from_click), 1);
            app.loadNewCurrentCell (apr(apr_idx).id);
            close (fig);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if key == "c"
                app.DrawCell = true;
                C = get(app.MainImageAxes,'CurrentPoint');
                app.p0.Position = [C(1,1) C(1,2)];
                app.p0.Visible = 'on';
                for i = 1:numel(app.p0.NodeChildren)-4
                    app.p0.NodeChildren(i).Size = 3;
                end
            end
            if key == "v"
                app.setBusyLamp(true);
                app.DrawCell = false;
                app.DrawCellBackwards = false;
                if app.AutosmoothCheckBox.Value
                    app.SmoothStreamlineButtonPushed();
                end
                app.makeMask();
                app.drawCurrentCell();
                app.BusyLamp.Color = [0.86 0.86 0.86];
                app.setBusyLamp(false);
            end
            if key == "e"
                C = get(app.MainImageAxes,'CurrentPoint');
                dx1 = app.p0.Position(1,1)-C(1,1);
                dy1 = app.p0.Position(1,1)-C(1,2);
                dr1 = sqrt(dx1^2+dy1^2);
                dx2 = app.p0.Position(end,1)-C(1,1);
                dy2 = app.p0.Position(end,1)-C(1,2);
                dr2 = sqrt(dx2^2+dy2^2);
                
                if dr2<dr1
                    app.DrawCell = true;
                else
                    app.DrawCellBackwards = true;
                end
                
                app.p0.Visible = 'on';
                for i = 1:numel(app.p0.NodeChildren)-4
                    app.p0.NodeChildren(i).Size = 3;
                end
            end
            if key == "s"
                app.ConfirmNewCellButtonPushed();
            end
            if key == "backspace"
                app.p0.Position(end,:) = [];
                for i = 1:numel(app.p0.NodeChildren)-4
                    app.p0.NodeChildren(i).Size = 3;
                end
                if app.AutosmoothCheckBox.Value
                    app.SmoothStreamlineButtonPushed();
                end
                app.makeMask();
                app.drawCurrentCell();
            end
            if key == "backquote"
                app.p0.Position(1,:) = [];
                for i = 1:numel(app.p0.NodeChildren)-4
                    app.p0.NodeChildren(i).Size = 3;
                end
                if app.AutosmoothCheckBox.Value
                    app.SmoothStreamlineButtonPushed();
                end
                app.makeMask();
                app.drawCurrentCell();
            end
            
            if key == "a"
                app.DrawCell = false;
                app.DrawCellBackwards = false;
                app.ApproveCurrentCellButtonPushed();
            end
            if key == "n"
                app.NextCellButtonPushed();
            end
            if key == "m"
                app.MoveScreen = true;
                app.CP = get(app.MainImageAxes,'CurrentPoint');
            end
            if key == "p"
                C = get(app.MainImageAxes,'CurrentPoint');
                mind = 10000;
                mini = 1;
                for i = 1:numel(app.CellList)
                    dx = app.CellList(i).refined(round(end/2),1)-C(1,1);
                    dy = app.CellList(i).refined(round(end/2),2)-C(1,2);
                    dr = sqrt(dx^2+dy^2);
                    if dr<mind
                        mind = dr;
                        mini = app.CellList(i).id;
                    end
                end
                app.loadNewCurrentCell(mini);
                app.drawApprovedCells(mini);
            end
            
            if key == "t"
                C = get(app.CurrentCellAxes,'CurrentPoint');
                app.Mask0(round(C(1,2)),round(C(1,1))) = 1;
                app.drawCurrentCell();
            end
            if key == "y"
                C = get(app.CurrentCellAxes,'CurrentPoint');
                app.Mask0(round(C(1,2)),round(C(1,1))) = 0;
                app.drawCurrentCell();
            end
            
            % Edit the mask of the image.
            if app.Editmask0background1cells2gunkButton.Value
                if key == "0"
                    C = get(app.MainImageAxes,'CurrentPoint');
                    tempmask = zeros(size(app.Mask));
                    tempmask(round(C(1,2)),round(C(1,1))) = 1;
                    tempmask = imdilate(tempmask,... 
                            strel('disk',app.SpotsizeEditField.Value));
                    app.Mask(tempmask==1) = 0;
                    app.MaskContour.ZData = app.Mask;
                elseif key == "1"
                    C = get(app.MainImageAxes,'CurrentPoint');
                    tempmask = zeros(size(app.Mask));
                    tempmask(round(C(1,2)),round(C(1,1))) = 1;
                    tempmask = imdilate(tempmask,... 
                            strel('disk',app.SpotsizeEditField.Value));
                    app.Mask(tempmask==1) = 1;
                    app.MaskContour.ZData = app.Mask;
                elseif key =="2"
                    C = get(app.MainImageAxes,'CurrentPoint');
                    tempmask = zeros(size(app.Mask));
                    tempmask(round(C(1,2)),round(C(1,1))) = 1;
                    tempmask = imdilate(tempmask,... 
                            strel('disk',app.SpotsizeEditField.Value));
                    app.Mask(tempmask==1) = 2;
                    app.MaskContour.ZData = app.Mask;
                end
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if key == "m"
                app.MoveScreen = false;
            end
        end

        % Button pushed function: ConfirmNewCellButton
        function ConfirmNewCellButtonPushed(app, event)
            new_id = max (arrayfun (@(x) x.id, app.CellList)) + 1;
            % create cell data
            celldata = app.cellDataFromStreamline (new_id, app.p0.Position, app.Mask0);
            celldata.approved = true;   
            % add to celllist and load for refinement
            app.CellList(end+1) = celldata;
%             app.loadNewCurrentCell(new_id);
%             [id, ~] = app.getNextUnapproved();
%             app.loadNewCurrentCell (id);
            n_approved = sum (arrayfun (@(x) x.approved, app.CellList));
%             if n_approved > 1

            app.drawApprovedCells (new_id);
            app.p0.Visible = 'off';

%             else
%                 app.drawApprovedCells ();
%             end
            % increment approved counter
            app.NumApprovedEditField.Value = n_approved;
            drawnow;

        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            
            xlims1 = get(app.MainImageAxes,'xlim');
            ylims1 = get(app.MainImageAxes,'ylim');
            
            app.MainImageAxes.XLim = [xlims1(1) - verticalScrollCount...
                xlims1(2) + verticalScrollCount];
            app.MainImageAxes.YLim = [ylims1(1) - verticalScrollCount...
                ylims1(2) + verticalScrollCount];
            
            app.MainBox.XData = app.MainImageAxes.XLim([1 1 2 2 1]);
            app.MainBox.YData = app.MainImageAxes.YLim([1 2 2 1 1]);
            app.ApprovedImageAxes.XLim = app.xlims;
            app.ApprovedImageAxes.YLim = app.ylims;
            app.CurrentCellAxes.XLim = app.MainImageAxes.XLim;
            app.CurrentCellAxes.YLim = app.MainImageAxes.YLim;
            
        end

        % Value changed function: ShowExistingCheckBox
        function ShowExistingCheckBoxValueChanged(app, event)
            value = app.ShowExistingCheckBox.Value;
            if value
                app.CellsPlot2.Visible = 'on';
            else
                app.CellsPlot2.Visible = 'off';
            end 
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.MovePoints = true;
            C = get(app.MainImageAxes,'CurrentPoint');
            pts = app.p0.Position;
            mind = 100;
            for i = 1:numel(pts(:,1))
                dx = C(1,1) - pts(i,1);
                dy = C(1,2) - pts(i,2);
                dr = sqrt(dx^2+dy^2);
                if dr<mind
                    mind = dr;
                    app.MovePointi = i;
                end
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.MovePoints = false;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.DrawCell
                C = get(app.MainImageAxes,'CurrentPoint');
                dx = C(1,1)-app.p0.Position(end,1);
                dy = C(1,2)-app.p0.Position(end,2);
                dr = sqrt(dx^2+dy^2);
                if dr > 1
                    app.p0.Position = [app.p0.Position; C(1,1) C(1,2)];
                end
                
                for i = 1:numel(app.p0.NodeChildren)-4
                    app.p0.NodeChildren(i).Size = 3;
                end
            end
            if app.DrawCellBackwards
                C = get(app.MainImageAxes,'CurrentPoint');
                dx = C(1,1)-app.p0.Position(1,1);
                dy = C(1,2)-app.p0.Position(1,2);
                dr = sqrt(dx^2+dy^2);
                if dr > 1
                    app.p0.Position = [C(1,1) C(1,2); app.p0.Position];
                end
                
                for i = 1:numel(app.p0.NodeChildren)-4
                    app.p0.NodeChildren(i).Size = 3;
                end
            end
            if app.MoveScreen
                C = get(app.MainImageAxes,'CurrentPoint');
                dx = C(1,1)-app.CP(1,1);
                dy = C(1,2)-app.CP(1,2);
                xlims1 = get(app.MainImageAxes,'XLim');
                ylims1 = get(app.MainImageAxes,'YLim');
                app.MainImageAxes.XLim = xlims1-dx;
                app.MainImageAxes.YLim = ylims1-dy;
                app.MainBox.XData = app.MainImageAxes.XLim([1 1 2 2 1]);
                app.MainBox.YData = app.MainImageAxes.YLim([1 2 2 1 1]);
                app.ApprovedImageAxes.XLim = app.xlims;
                app.ApprovedImageAxes.YLim = app.ylims;
                app.CurrentCellAxes.XLim = app.MainImageAxes.XLim;
                app.CurrentCellAxes.YLim = app.MainImageAxes.YLim;
                
                app.CP = get(app.MainImageAxes,'CurrentPoint');
            end
            if app.MovePoints
                C = get(app.MainImageAxes,'CurrentPoint');
                app.p0.Position(app.MovePointi,:) = [C(1,1) C(1,2)];
            end
                
        end

        % Value changed function: CellwidthEditField
        function CellwidthEditFieldValueChanged(app, event)
            value = app.CellwidthEditField.Value;
            app.makeMask();
            app.drawCurrentCell();
                
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            app.makeMask();
            app.drawCurrentCell();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {567, 567, 567};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {567, 567};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {313, '1x', 223};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CurrentCellAxes = uiaxes(app.LeftPanel);
            title(app.CurrentCellAxes, 'Current Cell mask (t / y - add / remove pixels)')
            app.CurrentCellAxes.XTick = [];
            app.CurrentCellAxes.YTick = [];
            app.CurrentCellAxes.ZTick = [];
            app.SmoothnessEditFieldLabel = uilabel(app.LeftPanel);
            app.SmoothnessEditFieldLabel.Text = 'Smoothness';
            app.SmoothnessEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SmoothnessEditField.Value = 5;
            app.FindCellsParametersLabel = uilabel(app.LeftPanel);
            app.FindCellsParametersLabel.Text = 'FindCells Parameters';
            app.MaxLengthpixEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxLengthpixEditFieldLabel.Text = 'Max. Length (pix)';
            app.MaxLengthpixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxLengthpixEditField.Value = 80;
            app.MinLengthpixEditFieldLabel = uilabel(app.LeftPanel);
            app.MinLengthpixEditFieldLabel.Text = 'Min. Length (pix)';
            app.MinLengthpixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinLengthpixEditField.Value = 10;
            app.BrightnessThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.BrightnessThresholdEditFieldLabel.Text = 'Brightness Threshold';
            app.BrightnessThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BrightnessThresholdEditField.Value = 0.5;
            app.FindCellsButton = uibutton(app.LeftPanel, 'push');
            app.FindCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FindCellsButtonPushed, true);
            app.FindCellsButton.Text = 'Find Cells';
            app.NextCell = uibutton(app.LeftPanel, 'push');
            app.NextCell.ButtonPushedFcn = createCallbackFcn(app, @NextCellButtonPushed, true);
            app.NextCell.Text = 'Next (n)';
            app.ApproveCurrentCell = uibutton(app.LeftPanel, 'push');
            app.ApproveCurrentCell.ButtonPushedFcn = createCallbackFcn(app, @ApproveCurrentCellButtonPushed, true);
            app.ApproveCurrentCell.Text = 'Approve (a)';
            app.SmoothingScaleEditFieldLabel = uilabel(app.LeftPanel);
            app.SmoothingScaleEditFieldLabel.Text = 'Smoothing Scale';
            app.SmoothingScaleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SmoothingScaleEditField.Limits = [-1 Inf];
            app.SmoothingScaleEditField.Value = 3;
            app.RefineManuallyButton = uibutton(app.LeftPanel, 'push');
            app.RefineManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @RefineManuallyButtonPushed, true);
            app.RefineManuallyButton.Text = 'Refine Manually';
            app.BusyLampLabel = uilabel(app.LeftPanel);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.LeftPanel);
            app.CellwidthEditFieldLabel = uilabel(app.LeftPanel);
            app.CellwidthEditFieldLabel.Text = 'Cell width';
            app.CellwidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CellwidthEditField.Limits = [1 Inf];
            app.CellwidthEditField.ValueChangedFcn = createCallbackFcn(app, @CellwidthEditFieldValueChanged, true);
            app.CellwidthEditField.Value = 3;
            app.ExpandCropButton = uibutton(app.LeftPanel, 'push');
            app.ExpandCropButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandCropButtonPushed, true);
            app.ExpandCropButton.Text = 'Expand Crop';
            app.SmoothStreamlineButton = uibutton(app.LeftPanel, 'push');
            app.SmoothStreamlineButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothStreamlineButtonPushed, true);
            app.SmoothStreamlineButton.Text = 'Smooth Streamline';
            app.ResetStreamlineButton = uibutton(app.LeftPanel, 'push');
            app.ResetStreamlineButton.ButtonPushedFcn = createCallbackFcn(app, @ResetStreamlineButtonPushed, true);
            app.ResetStreamlineButton.Text = 'Reset Streamline';
            app.OverlayalphaEditFieldLabel = uilabel(app.LeftPanel);
            app.OverlayalphaEditFieldLabel.Text = 'Overlay alpha';
            app.OverlayalphaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ApprovedLampLabel = uilabel(app.LeftPanel);
            app.ApprovedLampLabel.Text = 'Approved';
            app.ApprovedLamp = uilamp(app.LeftPanel);
            app.LoadExistingButton = uibutton(app.LeftPanel, 'push');
            app.LoadExistingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingButtonPushed, true);
            app.LoadExistingButton.Text = 'Load Existing';
            app.PrevCell = uibutton(app.LeftPanel, 'push');
            app.PrevCell.ButtonPushedFcn = createCallbackFcn(app, @PrevCellButtonPushed, true);
            app.PrevCell.Text = 'Prev.';
            app.NextUnapprovedButton = uibutton(app.LeftPanel, 'push');
            app.NextUnapprovedButton.ButtonPushedFcn = createCallbackFcn(app, @NextUnapprovedButtonPushed, true);
            app.NextUnapprovedButton.Text = 'Next Unapproved';
            app.AutosmoothCheckBox = uicheckbox(app.LeftPanel);
            app.AutosmoothCheckBox.Text = 'Auto smooth';
            app.AutosmoothCheckBox.Value = true;
            app.UnapproveCurrentCell = uibutton(app.LeftPanel, 'push');
            app.UnapproveCurrentCell.ButtonPushedFcn = createCallbackFcn(app, @UnapproveCurrentCellButtonPushed, true);
            app.UnapproveCurrentCell.Text = 'Unapprove';
            app.SkipapprovedCheckBox = uicheckbox(app.LeftPanel);
            app.SkipapprovedCheckBox.Text = 'Skip approved';
            app.SkipapprovedCheckBox.Value = true;
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.LeftPanel);
            app.MethodDropDown.Items = {'Spherocylinder', 'Brightness'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Brightness';
            app.CenterPanel = uipanel(app.GridLayout);
            app.MainImageAxes = uiaxes(app.CenterPanel);
            title(app.MainImageAxes, 'Draw cells')
            app.MainImageAxes.XTick = [];
            app.MainImageAxes.YTick = [];
            app.ApprovedImageAxes = uiaxes(app.CenterPanel);
            title(app.ApprovedImageAxes, 'Approved Cells')
            app.ApprovedImageAxes.YDir = 'reverse';
            app.ApprovedImageAxes.XTick = [];
            app.ApprovedImageAxes.YTick = [];
            app.CellsPlotAxes = uiaxes(app.CenterPanel);
            title(app.CellsPlotAxes, 'Approved Cells')
            app.CellsPlotAxes.YDir = 'reverse';
            app.CellsPlotAxes.CLim = [0 1];
            app.CellsPlotAxes.ALim = [0 1];
            app.CellsPlotAxes.XTick = [];
            app.CellsPlotAxes.YTick = [];
            app.Editmask0background1cells2gunkButton = uibutton(app.CenterPanel, 'state');
            app.Editmask0background1cells2gunkButton.Text = 'Edit mask (0 = background, 1 = cells, 2 = gunk)';
            app.SpotsizeEditFieldLabel = uilabel(app.CenterPanel);
            app.SpotsizeEditFieldLabel.Text = 'Spot size';
            app.SpotsizeEditField = uieditfield(app.CenterPanel, 'numeric');
            app.SpotsizeEditField.Value = 3;
            app.RightPanel = uipanel(app.GridLayout);
            app.SaveDataButton = uibutton(app.RightPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.NumApprovedEditFieldLabel = uilabel(app.RightPanel);
            app.NumApprovedEditFieldLabel.Text = 'Num. Approved';
            app.NumApprovedEditField = uieditfield(app.RightPanel, 'numeric');
            app.NumApprovedEditField.Editable = 'off';
            app.TotalNumCellsEditFieldLabel = uilabel(app.RightPanel);
            app.TotalNumCellsEditFieldLabel.Text = 'Total Num. Cells';
            app.TotalNumCellsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TotalNumCellsEditField.Editable = 'off';
            app.AddNewCellButton = uibutton(app.RightPanel, 'push');
            app.AddNewCellButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewCellButtonPushed, true);
            app.AddNewCellButton.Text = 'Add New Cell';
            app.EnlargeFindCellsResultButton = uibutton(app.RightPanel, 'push');
            app.EnlargeFindCellsResultButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeFindCellsResultButtonPushed, true);
            app.EnlargeFindCellsResultButton.Text = 'Enlarge FindCells Result';
            app.EnlargeApprovedCellsButton = uibutton(app.RightPanel, 'push');
            app.EnlargeApprovedCellsButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeApprovedCellsButtonPushed, true);
            app.EnlargeApprovedCellsButton.Text = 'Enlarge Approved Cells';
            app.GotoCellButton = uibutton(app.RightPanel, 'push');
            app.GotoCellButton.ButtonPushedFcn = createCallbackFcn(app, @GotoCellButtonPushed, true);
            app.GotoCellButton.Text = 'Go to Cell';
            app.ShowExistingCheckBox = uicheckbox(app.RightPanel);
            app.ShowExistingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowExistingCheckBoxValueChanged, true);
            app.ShowExistingCheckBox.Text = 'Show Existing';
            app.ConfirmNewCellButton = uibutton(app.RightPanel, 'push');
            app.ConfirmNewCellButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmNewCellButtonPushed, true);
            app.ConfirmNewCellButton.Text = 'Confirm New Cell';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Value = {'Hotkeys:'; '(Note: Click on the Draw cells plot if hotkeys aren''t working)'; ''; 'Backspace - delete point off the end of the cell spline.'; ''; 'hold m - drag the screen around.'; 'scroll wheel - zoom'; ''; 'press (don''t hold) "c" / "v" - start / finish drawing the current cell from scratch, to refine manually.'; ''; 'press "e" - add points to the end of the current cell.'; ''; 'To add a new cell click "Add New Cell", draw your cell using "c" / "v" as above, click "Confirm New Cell", or press "s".'; ''; 'press "p" - select cell closest to your mouse.'; ''; ''};
            app.CurrentIDLabel = uilabel(app.RightPanel);
            app.CurrentIDLabel.Text = 'Current ID:';
            app.CID = uilabel(app.RightPanel);
            app.CID.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spineLabeler(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end