classdef cellmasker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SmoothnessEditFieldLabel      matlab.ui.control.Label
        SmoothnessEditField           matlab.ui.control.NumericEditField
        FindCellsParametersLabel      matlab.ui.control.Label
        MaxLengthpixEditFieldLabel    matlab.ui.control.Label
        MaxLengthpixEditField         matlab.ui.control.NumericEditField
        MinLengthpixEditFieldLabel    matlab.ui.control.Label
        MinLengthpixEditField         matlab.ui.control.NumericEditField
        BrightnessThresholdEditFieldLabel  matlab.ui.control.Label
        BrightnessThresholdEditField  matlab.ui.control.NumericEditField
        FindCellsButton               matlab.ui.control.Button
        NextCell                      matlab.ui.control.Button
        ApproveCurrentCell            matlab.ui.control.Button
        ExpansionSizeEditFieldLabel   matlab.ui.control.Label
        ExpansionSizeEditField        matlab.ui.control.NumericEditField
        SmoothingScaleEditFieldLabel  matlab.ui.control.Label
        SmoothingScaleEditField       matlab.ui.control.NumericEditField
        AutomaticRefinementButton     matlab.ui.control.Button
        RefineManuallyButton          matlab.ui.control.Button
        BusyLampLabel                 matlab.ui.control.Label
        BusyLamp                      matlab.ui.control.Lamp
        SubsampleEditFieldLabel       matlab.ui.control.Label
        SubsampleEditField            matlab.ui.control.NumericEditField
        ExpandCropButton              matlab.ui.control.Button
        SmoothContourButton           matlab.ui.control.Button
        ResetContourButton            matlab.ui.control.Button
        CropPaddingEditFieldLabel     matlab.ui.control.Label
        CropPaddingEditField          matlab.ui.control.NumericEditField
        ApprovedLampLabel             matlab.ui.control.Label
        ApprovedLamp                  matlab.ui.control.Lamp
        LoadExistingButton            matlab.ui.control.Button
        PrevCell                      matlab.ui.control.Button
        NextUnapprovedButton          matlab.ui.control.Button
        ShowApprovedCellsCheckBox     matlab.ui.control.CheckBox
        UnapproveCurrentCell          matlab.ui.control.Button
        CurrentCellAxes               matlab.ui.control.UIAxes
        CenterPanel                   matlab.ui.container.Panel
        MainImageAxes                 matlab.ui.control.UIAxes
        ApprovedImageAxes             matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        SaveDataButton                matlab.ui.control.Button
        NumApprovedEditFieldLabel     matlab.ui.control.Label
        NumApprovedEditField          matlab.ui.control.NumericEditField
        TotalNumCellsEditFieldLabel   matlab.ui.control.Label
        TotalNumCellsEditField        matlab.ui.control.NumericEditField
        AddNewCellButton              matlab.ui.control.Button
        EnlargeFindCellsResultButton  matlab.ui.control.Button
        EnlargeApprovedCellsButton    matlab.ui.control.Button
        GotoCellButton                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Image = []; % main image
        Mask = [];  % cell mask (1 => cells)
        CellList = [];     % list generated by `findcells`
        % struct that holds current cell data
        CurrentCellData = struct ('id', nan, ...
                'crop_box', nan(1,4), ...
                'msk_crop', [], ...
                'bwboundary', nan(2,1), ...
                'contour', nan (2,1), ...
                'approved', false);
        % struct that holds args for imageForces
        ImageForceFnArgs = struct (...
            'sigma_edge', 1.25, ...
            'grad_smA', 2, ...
            'thresh_min', 0.66, ...
            'thresh_fac', 0.7, ...
            'grad_wgt', 1.3, ...
            'thr_wgt', 0.8, ...
            'dxy_wgt', 1);
        % struct holding args for refineContour
        RefineContourFnArgs = struct (...
            'thres', 0.61, ...
            'cell_width', 2, ...
            'max_area', 2e4, ...
            'rigid_range', 2.5, ...
            'rigid_rangeB', 7, ...
            'rigidity', 1.63, ...
            'rigidityB', 3, ...
            'springconst', 0.2, ...
            'scalefactor', 0.1, ...
            'fitstep', 0.15, ...
            'fitmaxiter', 180, ...
            'img_force', 7, ...
            'attr_power', 7, 'attr_coeff', 0.62, ...
            'rep_coeff', 0.98, 'neigh_rep', 18, ...
            'thresh_facF', 0.985, ...
            'horz_align', 0.2, ...
            'eq_dist', 2.5, ...
            'mov_all', 0.15);
    end
    
    methods (Access = private)
        
        function drawImage(app, plot_boundary)
            if nargin == 1 
                plot_boundary = true;
            end
            % show image and mask boundaries
            imagesc (app.MainImageAxes, app.Image);
            colormap (app.MainImageAxes, 'gray');
            set (app.MainImageAxes, 'XLimSpec', 'Tight')
            set (app.MainImageAxes, 'YLimSpec', 'Tight')
            if plot_boundary
                % show mask boundaries
                B = bwboundaries (app.Mask);
                hold (app.MainImageAxes, 'on')
                for ib = 1:numel(B)
                    bound = B{ib};
                    plot (app.MainImageAxes, bound(:,2), bound(:,1), 'k', ...
                        'LineWidth', 1)
                end
                hold (app.MainImageAxes, 'off')
            end
        end
        
        function clearApprovedCells(app)
            for ci = 1:numel(app.CellList)
                app.CellList(ci).approved = false;
            end
            app.drawApprovedCells();
        end
        
        function loadNewCurrentCell (app, id)
            idx = app.foundCellIndexForId (id);
            app.CurrentCellData = app.CellList(idx);
            app.drawCurrentCell();
            if (app.CurrentCellData.approved)
                app.ApprovedLamp.Color = [0 1 0];
            else
                app.ApprovedLamp.Color = [1 0 0];
            end
        end
        
        function drawCurrentCell (app)
            % draw the cell image
            crop_box = app.CurrentCellData.crop_box;
            cla (app.CurrentCellAxes, 'reset');
            imagesc (app.CurrentCellAxes, imcrop (app.Image, crop_box));
            colormap (app.CurrentCellAxes, 'gray');
            set (app.CurrentCellAxes, 'XLimSpec', 'Tight')
            xlim = get (app.CurrentCellAxes, 'XLim');
            set (app.CurrentCellAxes, 'YLimSpec', 'Tight')
            ylim = get (app.CurrentCellAxes, 'YLim');
            % plot the contour
            contour = app.CurrentCellData.contour;
            hold (app.CurrentCellAxes, 'on')
            plot (app.CurrentCellAxes, contour(:,1), contour(:,2), 'b')
            hold (app.CurrentCellAxes, 'off');
            if (app.ShowApprovedCellsCheckBox.Value)
                glbMeanPos = @(c) crop2globalCoords (mean (c.contour), c.crop_box);
                % plot nearby approved cell contours
                apr = app.CellList(arrayfun (@(x) x.approved, app.CellList));
                if not (isempty (apr))
                    glb_muz = arrayfun (glbMeanPos, apr, 'UniformOutput', false);
                    mu = glbMeanPos (app.CurrentCellData);
                    dist = cellfun (@(g) sqrt (sum ((g-mu).^2)), glb_muz);
                    to_plot = apr(dist < 300 & dist > 1);
                    hold (app.CurrentCellAxes, 'on')
                    for pii = 1:length(to_plot)
                        coords = crop2cropCoords (to_plot(pii).contour, ...
                            to_plot(pii).crop_box, app.CurrentCellData.crop_box);
                        plot (app.CurrentCellAxes, coords(:,1), coords(:,2), 'r')
                    end
                    hold (app.CurrentCellAxes, 'off')
                end
            end
            set (app.CurrentCellAxes, 'XLim', xlim);
            set (app.CurrentCellAxes, 'YLim', ylim);
        end
        
        function setBusyLamp (app, is_busy)
            if is_busy
                app.BusyLamp.Color = [0 1 0];
            else
                app.BusyLamp.Color = [0.86 0.86 0.86];
            end
            drawnow;
        end
        
        function drawApprovedCells(app)
            % show image
            imagesc (app.ApprovedImageAxes, app.Image);
            colormap (app.ApprovedImageAxes, 'gray');
            set (app.ApprovedImageAxes, 'XLimSpec', 'Tight')
            set (app.ApprovedImageAxes, 'YLimSpec', 'Tight')
            % loop through found cells, only plotting approved
            hold (app.ApprovedImageAxes, 'on')
            for idx = 1:length(app.CellList)
                this = app.CellList(idx);
                if not (this.approved), continue, end
                contour_glb = crop2globalCoords (this.contour, this.crop_box);
                plot (app.ApprovedImageAxes, ...
                        contour_glb(:,1), contour_glb(:,2))
            end
            hold (app.ApprovedImageAxes, 'off')
        end
        
        function drawApprovedCell (app, idx)
            if not (app.CellList(idx).approved)
                warndlg ('cell isnt approved')
                return
            end
            hold (app.ApprovedImageAxes, 'on')
            this = app.CellList(idx);
            contour_glb = crop2globalCoords (this.contour, this.crop_box);
            plot (app.ApprovedImageAxes, ...
                    contour_glb(:,1), contour_glb(:,2))
            hold (app.ApprovedImageAxes, 'off')
        end
        
        function idx = foundCellIndexForId(app, id)
            idx = find (arrayfun (@(x) x.id == id, app.CellList), 1);
        end
        
        
        function [next_id, idx] = getNextUnapproved(app)
            % get id's and cells that aren't approved
            idz = arrayfun (@(x) x.id, app.CellList);
            npr = not (arrayfun (@(x) x.approved, app.CellList));
            % figure out which cell to look at next
            next_id = find (idz > app.CurrentCellData.id & npr, 1, 'first');
            if isempty (next_id)
                next_id = min (idz(npr));
            end
            if not (isempty (next_id))
                idx = find (idz == next_id, 1, 'first');
            else
                idx = [];
            end
        end
        
        function drawFoundCellStreamlines(app, plot_boundary)
            app.drawImage (plot_boundary);
            hold (app.MainImageAxes, 'on')
            for ic = 1:length(app.CellList)
                A = app.CellList(ic).pix;
                plot (app.MainImageAxes, A(:,1), A(:,2))
            end
            hold (app.MainImageAxes, 'off')
        end
        
        function mask = makeApprovedCellMask (app, label)
            if nargin == 1, label = true; end
            [nr, nc] = size (app.Image);
            mask = zeros (nr, nc);
            for ic = 1:length(app.CellList)
                if app.CellList(ic).approved
                    this_mask = poly2mask (app.CellList(ic).contour(:,1), ...
                        app.CellList(ic).contour(:,2), nr, nc);
                    this_mask = this_mask .* app.CellList(ic).id;
                    mask = max (mask, this_mask);
                end
            end
            if label
                mask = label2rgb (mask, 'jet', [1 1 1], 'shuffle');
            end
        end
    end
    
    methods (Static)
        
        function data = cellDataFromStreamline (lbl_mask, id, pix)
            % figure out cell ends
            xse = pix([1 end], 1);
            yse = pix([1 end], 2);
            % crop image and display
            crop_box = cropBoxFromCellEnds (xse, yse, 20, true);
            % crop label mask and display boundary
            msk_crop = imcrop (lbl_mask, crop_box);
            msk_crop = msk_crop == id;
            contour_rc = cellmask2contour (msk_crop, -1);
            contour = rc2xy (size (msk_crop), contour_rc);
            data = struct ('id', id, ...
                'pix', pix, ...
                'crop_box', crop_box, ...
                'bwboundary', contour, ...
                'contour', contour, ...
                'approved', false);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, mask)
            % add supplement folder to path
            fpath = mfilename ('fullpath');
            [this_fldr, ~, ~] = fileparts (fpath);
            addpath (fullfile (this_fldr, 'supp'));
            
            % input validation
            assert (length (size (img)) == 2, 'img must be 2d array')
            assert (all (size (img) == size (mask)), ...
                'input image and mask must be same size')
            if not (isa (img, 'double'))
                img = im2double (img);
            end
            if not (islogical (mask))
               mask = not (mask == 0);
            end
            app.Image = normalizeImage (img);
            app.Mask = mask;
            % draw image
            app.drawImage (true);
            app.setBusyLamp (false);
        end

        % Button pushed function: FindCellsButton
        function FindCellsButtonPushed(app, event)
            n_approved = sum (arrayfun (@(x) x.approved, app.CellList));
            % make sure user wants to proceed...
            if n_approved > 0
                answer = questdlg (...
                    "Re-finding cells will clear all progress. Proceed?", ...
                    "Restart approval?", "yes", "no", "no");
                if strcmp (answer, "no") % they don't
                    return
                end
            end

            % if we got here, we're good to go
            app.clearApprovedCells();
            
            app.setBusyLamp(true);
            % run findcells and plot answer
            cellz = findcells (app.Image, not (app.Mask), ...
                app.SmoothnessEditField.Value, ...
                app.MaxLengthpixEditField.Value, ...
                app.MinLengthpixEditField.Value, ...
                app.BrightnessThresholdEditField.Value);
            % sort by x/y distance from (0,0)
            function [dist] = meanXYdist (c)
               meanpos = mean (c.pix, 1);
               dist = sqrt (sum (meanpos.^2));
            end
            ds = arrayfun (@meanXYdist, cellz);
            [~, idx] = sort (ds);
            cellz = cellz(idx);
            % do the expansion
            [lbl_mask, cellz] = cells2mask (cellz, size (app.Image), ...
                true, app.ExpansionSizeEditField.Value);
            % generate list of found cells
            app.CellList = arrayfun (...
                @(c) app.cellDataFromStreamline (lbl_mask, c.id, c.pix), ...
                cellz);
            % load first cell as current
            app.loadNewCurrentCell(1);
            app.TotalNumCellsEditField.Value = length (app.CellList);
            app.NumApprovedEditField.Value = 0;
            app.drawFoundCellStreamlines (false);
            app.setBusyLamp(false);
        end

        % Button pushed function: AutomaticRefinementButton
        function AutomaticRefinementButtonPushed(app, event)
            % ask user if they want to use default params
            answer = questdlg ('Use current default parameters?', ...
                'Default Parameters', 'yes', 'no', 'yes');
            if strcmp (answer, 'no')  % they don't
                % get values for image force calculation
                prompts = fieldnames (app.ImageForceFnArgs);
                defvals = cellfun (@(n) num2str (app.ImageForceFnArgs.(n)), ...
                    prompts, 'UniformOutput', false);
                imgFargs = inputdlg (prompts, 'Image Force Options', ...
                    [1 20], defvals);
                imgFargs = cellfun (@str2double, imgFargs);
                for idx = 1:numel(prompts)
                    app.ImageForceFnArgs.(prompts{idx}) = imgFargs(idx);
                end
                % get values for contour refinement
                prompts = fieldnames (app.RefineContourFnArgs);
                defvals = cellfun (@(n) num2str (app.RefineContourFnArgs.(n)), ...
                    prompts, 'UniformOutput', false);
                refCargs1 = inputdlg (prompts(1:10), 'Refine Contour Options', ...
                    [1 20], defvals(1:10));
                refCargs1 = cellfun (@str2double, refCargs1);
                refCargs2 = inputdlg (prompts(11:end), 'Refine Contour Options', ...
                    [1 20], defvals(11:end));
                refCargs2 = cellfun (@str2double, refCargs2);
                refCargs = horzcat (refCargs1, refCargs2);
                for idx = 1:numel(prompts)
                    app.RefineContourFnArgs.(prompts{idx}) = refCargs(idx);
                end
            end
            app.setBusyLamp (true);
            
            % crop image to current cell
            img = imcrop (app.Image, app.CurrentCellData.crop_box);
            im16 = im2uint16 (img);
            
            % do the computation
            [E_dx, E_dy, ~, ~] = imageForces (img, im16, ...
                app.ImageForceFnArgs.sigma_edge, app.ImageForceFnArgs.grad_smA, ...
                app.ImageForceFnArgs.thresh_min, app.ImageForceFnArgs.thresh_fac, ...
                app.ImageForceFnArgs.grad_wgt, app.ImageForceFnArgs.thr_wgt, ...
                app.ImageForceFnArgs.dxy_wgt );
            refined = refineContour (1-img, E_dx, E_dy, ...
                app.RefineContourFnArgs.thres, app.CurrentCellData.contour, ...
                app.RefineContourFnArgs.cell_width, app.RefineContourFnArgs.max_area, ...
                app.RefineContourFnArgs.rigid_range, app.RefineContourFnArgs.rigid_rangeB, ...
                app.RefineContourFnArgs.rigidity, app.RefineContourFnArgs.rigidityB, ...
                app.RefineContourFnArgs.springconst, app.RefineContourFnArgs.scalefactor, ...
                app.RefineContourFnArgs.fitstep, app.RefineContourFnArgs.fitmaxiter, ...
                app.RefineContourFnArgs.img_force, app.RefineContourFnArgs.attr_power, ...
                app.RefineContourFnArgs.attr_coeff, app.RefineContourFnArgs.rep_coeff, ...
                app.RefineContourFnArgs.neigh_rep, app.RefineContourFnArgs.thresh_facF, ...
                app.RefineContourFnArgs.horz_align, app.RefineContourFnArgs.eq_dist, ...
                app.RefineContourFnArgs.mov_all);
            if isempty (refined)
                warndlg ('automatic refinement failed')
            else
                app.CurrentCellData.contour = refined;
                app.drawCurrentCell();
            end
            app.setBusyLamp (false);
        end

        % Button pushed function: RefineManuallyButton
        function RefineManuallyButtonPushed(app, event)
            if isnan (app.CurrentCellData.id)
                warndlg ('no cell to refine')
                return
            end
            % show big image to do adjustment in
            fig = figure(1);
            axes (fig.CurrentAxes, 'Units', 'normalize', 'Position', [0 0 1 1])
            imshow (imcrop (app.Image, app.CurrentCellData.crop_box));
%            colormap (fig.CurrentAxes, 'gray')
            curr_pos = get (fig, 'Position');
            set (fig, 'Position', [curr_pos(1:2) 400 400]);
            % draw polygon and wait for user to double-click to confirm
            skip = app.SubsampleEditField.Value;
            if (skip > 0)
                contour = app.CurrentCellData.contour(1:skip:end,:);
            else
                contour = app.CurrentCellData.contour;
            end
            if (app.ShowApprovedCellsCheckBox.Value)
                glbMeanPos = @(c) crop2globalCoords (mean (c.contour), c.crop_box);
                % plot nearby approved cell contours
                apr = app.CellList(arrayfun (@(x) x.approved, app.CellList));
                if not (isempty (apr))
                    glb_muz = arrayfun (glbMeanPos, apr, 'UniformOutput', false);
                    mu = glbMeanPos (app.CurrentCellData);
                    dist = cellfun (@(g) sqrt (sum ((g-mu).^2)), glb_muz);
                    to_plot = apr(dist < 300 & dist > 1);
                    hold (fig.CurrentAxes, 'on')
                    for pii = 1:length(to_plot)
                        coords = crop2cropCoords (to_plot(pii).contour, ...
                            to_plot(pii).crop_box, app.CurrentCellData.crop_box);
                        plot (fig.CurrentAxes, coords(:,1), coords(:,2), 'r')
                    end
                    hold (fig.CurrentAxes, 'off')
                end
            end
            h = drawpolygon (fig.CurrentAxes, 'Position', contour);
            function clickCallback (~, evt)
                if strcmp (evt.SelectionType, 'double')
                    uiresume;
                end
            end
            function [pos] = customWait (hROI)
                list = addlistener (hROI, 'ROIClicked', @clickCallback);
                uiwait;
                delete (list);
                pos = hROI.Position;
            end
            roi = customWait (h);
            
            % convert to row/col & draw
            app.CurrentCellData.contour = roi;
            app.drawCurrentCell();
            close (fig);
        end

        % Button pushed function: ApproveCurrentCell
        function ApproveCurrentCellButtonPushed(app, event)
            % add to list of approved cells
            app.CurrentCellData.approved = true;
            curr_idx = find (arrayfun (@(x) x.id, app.CellList) == app.CurrentCellData.id, 1);
            app.CellList(curr_idx) = app.CurrentCellData;
            [id, ~] = app.getNextUnapproved();
            app.loadNewCurrentCell (id);
            n_approved = sum (arrayfun (@(x) x.approved, app.CellList));
            if n_approved > 1
                app.drawApprovedCell (curr_idx);
            else
                app.drawApprovedCells ();
            end
            % increment approved counter
            app.NumApprovedEditField.Value = n_approved;
            drawnow;
        end

        % Button pushed function: NextCell
        function NextCellButtonPushed(app, event)
            idz = arrayfun (@(x) x.id, app.CellList);
            idx = find (idz > app.CurrentCellData.id, 1, 'first');
            if isempty (idx)
                warndlg ('No next cell, at end of list');
                return
            end
            app.loadNewCurrentCell (idz(idx));
        end

        % Button pushed function: ExpandCropButton
        function ExpandCropButtonPushed(app, event)
            pad = app.CropPaddingEditField.Value;
            curr_box = app.CurrentCellData.crop_box;
            new_box = curr_box;
            new_box(1) = max (curr_box(1)-pad, 0);
            new_box(2) = max (curr_box(2)-pad, 0);
            actual_pads = curr_box(1:2) - new_box(1:2);
            new_box(3) = curr_box(3) + actual_pads(1)*2;
            new_box(4) = curr_box(4) + actual_pads(2)*2;
            app.CurrentCellData.crop_box = new_box;
            app.CurrentCellData.bwboundary = app.CurrentCellData.bwboundary + ...
                actual_pads;
            app.CurrentCellData.contour = app.CurrentCellData.contour + ...
                actual_pads;
            app.drawCurrentCell();
        end

        % Button pushed function: SmoothContourButton
        function SmoothContourButtonPushed(app, event)
            if isnan (app.CurrentCellData.id)
                warndlg ('no cell contour to smooth')
                return
            end
            app.setBusyLamp (true);
            smth = smoothContour (app.CurrentCellData.contour, ...
                app.SmoothingScaleEditField.Value);
            app.CurrentCellData.contour = smth;
            app.drawCurrentCell();
            app.setBusyLamp (false);
        end

        % Button pushed function: ResetContourButton
        function ResetContourButtonPushed(app, event)
            app.CurrentCellData.contour = app.CurrentCellData.bwboundary;
            app.drawCurrentCell();
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            [fname, ffldr] = uiputfile ({'*.mat', 'MatLab Data Files'}, 'Choose savefile path', 'cellmask.mat');
            Image = app.Image; Mask = app.Mask;
            cellList = app.CellList;
            forceFnArgs = app.ImageForceFnArgs;
            refineContourFnArgs = app.RefineContourFnArgs;
            save (fullfile (ffldr, fname), 'Image', 'Mask', 'cellList', ...
                'forceFnArgs', 'refineContourFnArgs');
        end

        % Button pushed function: PrevCell
        function PrevCellButtonPushed(app, event)
            idz = arrayfun (@(x) x.id, app.CellList);
            idx = find (idz < app.CurrentCellData.id, 1, 'last');
            if isempty (idx)
                warndlg ('no previous cell, at start of list');
                return
            end
            app.loadNewCurrentCell (idz(idx));
        end

        % Button pushed function: NextUnapprovedButton
        function NextUnapprovedButtonPushed(app, event)
            [next_id, ~] = app.getNextUnapproved();
            app.loadNewCurrentCell (next_id);
        end

        % Button pushed function: LoadExistingButton
        function LoadExistingButtonPushed(app, event)
            [fname, ffldr] = uigetfile ({'*.mat', 'MatLab data files'}, ...
                'Select file to load', 'cellmask.mat');
            fpath = fullfile (ffldr, fname);
            if not (exist (fpath, 'file'))
                warndlg ('selected file does not exist')
                return
            end
            answer = questdlg ('Keep current image or load from existing?', ...
                'Default Parameters', 'current', 'existing', 'current');
            props = load (fpath);
            % update properties
            if strcmp (answer, 'existing')
                app.Image = props.Image;
            end
            app.Mask = props.Mask;
            app.CellList = props.cellList;
            app.ImageForceFnArgs = props.forceFnArgs;
            app.RefineContourFnArgs = props.refineContourFnArgs;
            % use properties to update UI
            app.drawImage (false);
            app.drawFoundCellStreamlines (false);
            app.drawApprovedCells();
            % find min unapproved cell id and set as current
            apr = arrayfun (@(x) x.approved, app.CellList);
            npr_idx = find (not (apr), 1, 'first');
            if isempty (npr_idx)
                warndlg ('didnt find an unapproved cell')
                npr_idx = 1;
            end
            app.loadNewCurrentCell (app.CellList(npr_idx).id);
            % update counters
            app.TotalNumCellsEditField.Value = length (apr);
            app.NumApprovedEditField.Value = sum (apr);
        end

        % Button pushed function: AddNewCellButton
        function AddNewCellButtonPushed(app, event)
            fig = figure(1);
            imshow (app.Image);
            hold ('on')
            for idx = 1:length(app.CellList)
                if app.CellList(idx).approved
                    this = app.CellList(idx);
                    contour = this.contour;
                    box = this.crop_box;
                    coord = crop2globalCoords (contour, box);
                    plot (coord(:,1), coord(:,2))
                end
            end
            hold ('off')
            [xse, yse] = ginput (2);
            crop_box = cropBoxFromCellEnds(xse, yse, 20);
            % draw cell streamline
            imshow (imcrop (app.Image, crop_box));
            lne = drawpolyline (gca);
            glb_pos = crop2globalCoords (lne.Position, crop_box);
            dummy_cell = struct ('pix', glb_pos);
            [dummy_mask, ~] = cells2mask (dummy_cell, ...
                size (app.Image), false, app.ExpansionSizeEditField.Value);
            % update label mask
            new_id = max (arrayfun (@(x) x.id, app.CellList)) + 1;
            % create cell data
            celldata = app.cellDataFromStreamline (dummy_mask.*new_id, ...
                new_id, glb_pos);
            % add to celllist and load for refinement
            app.CellList(end+1) = celldata;
            app.loadNewCurrentCell(new_id);
            app.TotalNumCellsEditField.Value = app.TotalNumCellsEditField.Value + 1;
            close (fig)
        end

        % Button pushed function: EnlargeFindCellsResultButton
        function EnlargeFindCellsResultButtonPushed(app, event)
            % show results of findcells
            imshow (app.Image)
            hold ('on')
            for ic = 1:length(app.CellList)
                A = app.CellList(ic).pix;
                plot (A(:,1), A(:,2))
            end
            hold ('off')
        end

        % Button pushed function: EnlargeApprovedCellsButton
        function EnlargeApprovedCellsButtonPushed(app, event)
            % show approved cell contours
            imshow (app.Image)
            hold ('on')
            for ic = 1:length(app.CellList)
                this = app.CellList(ic);
                if (this.approved)
                    A = crop2globalCoords (this.contour, this.crop_box);
                    plot (A(:,1), A(:,2))
                end
            end
            hold ('off')
        end

        % Button pushed function: UnapproveCurrentCell
        function UnapproveCurrentCellButtonPushed(app, event)
            % add to list of approved cells
            app.CurrentCellData.approved = false;
            curr_idx = find (arrayfun (@(x) x.id, app.CellList) == app.CurrentCellData.id, 1);
            app.CellList(curr_idx) = app.CurrentCellData;
            [id, ~] = app.getNextUnapproved();
            app.loadNewCurrentCell (id);
            n_approved = sum (arrayfun (@(x) x.approved, app.CellList));
            if n_approved > 1
                app.drawApprovedCell (curr_idx);
            else
                app.drawApprovedCells ();
            end
            % increment approved counter
            app.NumApprovedEditField.Value = n_approved;
            drawnow;
        end

        % Button pushed function: GotoCellButton
        function GotoCellButtonPushed(app, event)
            % get approved cells
            apr = app.CellList(arrayfun (@(x) x.approved, app.CellList));
            if isempty (apr)
                warndlg ('no approved cells to go to');
                return
            end
            % show approved cell contours
            fig = figure(1);
            imshow (app.Image);
            hold ('on')
            for ic = 1:length(apr)
                A = crop2globalCoords (apr(ic).contour, apr(ic).crop_box);
                plot (A(:,1), A(:,2))
            end
            hold ('off')
            % get user input
            [xm, ym] = ginput (1);
            click_loc = [xm, ym];
            % compute mean position of contour for each cell
            mean_pos = arrayfun (...
                @(a) mean (crop2globalCoords (a.contour, a.crop_box)), apr, ...
                'UniformOutput', false);
            % "correct" cell is the closest one to mouse click
            dist_from_click = cellfun (@(a) sqrt (sum ((a-click_loc).^2)), ...
                mean_pos);
            apr_idx = find (dist_from_click == min (dist_from_click), 1);
            app.loadNewCurrentCell (apr(apr_idx).id);
            close (fig);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {567, 567, 567};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {567, 567};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {313, '1x', 223};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SmoothnessEditFieldLabel = uilabel(app.LeftPanel);
            app.SmoothnessEditFieldLabel.Text = 'Smoothness';
            app.SmoothnessEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SmoothnessEditField.Value = 5;
            app.FindCellsParametersLabel = uilabel(app.LeftPanel);
            app.FindCellsParametersLabel.Text = 'FindCells Parameters';
            app.MaxLengthpixEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxLengthpixEditFieldLabel.Text = 'Max. Length (pix)';
            app.MaxLengthpixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxLengthpixEditField.Value = 80;
            app.MinLengthpixEditFieldLabel = uilabel(app.LeftPanel);
            app.MinLengthpixEditFieldLabel.Text = 'Min. Length (pix)';
            app.MinLengthpixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinLengthpixEditField.Value = 10;
            app.BrightnessThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.BrightnessThresholdEditFieldLabel.Text = 'Brightness Threshold';
            app.BrightnessThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BrightnessThresholdEditField.Value = 0.5;
            app.FindCellsButton = uibutton(app.LeftPanel, 'push');
            app.FindCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FindCellsButtonPushed, true);
            app.FindCellsButton.Text = 'Find Cells';
            app.NextCell = uibutton(app.LeftPanel, 'push');
            app.NextCell.ButtonPushedFcn = createCallbackFcn(app, @NextCellButtonPushed, true);
            app.NextCell.Text = 'Next';
            app.ApproveCurrentCell = uibutton(app.LeftPanel, 'push');
            app.ApproveCurrentCell.ButtonPushedFcn = createCallbackFcn(app, @ApproveCurrentCellButtonPushed, true);
            app.ApproveCurrentCell.Text = 'Approve';
            app.ExpansionSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.ExpansionSizeEditFieldLabel.Text = 'Expansion Size';
            app.ExpansionSizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ExpansionSizeEditField.Value = 2;
            app.SmoothingScaleEditFieldLabel = uilabel(app.LeftPanel);
            app.SmoothingScaleEditFieldLabel.Text = 'Smoothing Scale';
            app.SmoothingScaleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SmoothingScaleEditField.Limits = [-1 Inf];
            app.AutomaticRefinementButton = uibutton(app.LeftPanel, 'push');
            app.AutomaticRefinementButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticRefinementButtonPushed, true);
            app.AutomaticRefinementButton.Text = 'Automatic Refinement';
            app.RefineManuallyButton = uibutton(app.LeftPanel, 'push');
            app.RefineManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @RefineManuallyButtonPushed, true);
            app.RefineManuallyButton.Text = 'Refine Manually';
            app.BusyLampLabel = uilabel(app.LeftPanel);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.LeftPanel);
            app.SubsampleEditFieldLabel = uilabel(app.LeftPanel);
            app.SubsampleEditFieldLabel.Text = 'Subsample';
            app.SubsampleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SubsampleEditField.Limits = [1 Inf];
            app.SubsampleEditField.Value = 2;
            app.ExpandCropButton = uibutton(app.LeftPanel, 'push');
            app.ExpandCropButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandCropButtonPushed, true);
            app.ExpandCropButton.Text = 'Expand Crop';
            app.SmoothContourButton = uibutton(app.LeftPanel, 'push');
            app.SmoothContourButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothContourButtonPushed, true);
            app.SmoothContourButton.Text = 'Smooth Contour';
            app.ResetContourButton = uibutton(app.LeftPanel, 'push');
            app.ResetContourButton.ButtonPushedFcn = createCallbackFcn(app, @ResetContourButtonPushed, true);
            app.ResetContourButton.Text = 'Reset Contour';
            app.CropPaddingEditFieldLabel = uilabel(app.LeftPanel);
            app.CropPaddingEditFieldLabel.Text = 'Crop Padding';
            app.CropPaddingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CropPaddingEditField.Value = 5;
            app.ApprovedLampLabel = uilabel(app.LeftPanel);
            app.ApprovedLampLabel.Text = 'Approved';
            app.ApprovedLamp = uilamp(app.LeftPanel);
            app.LoadExistingButton = uibutton(app.LeftPanel, 'push');
            app.LoadExistingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingButtonPushed, true);
            app.LoadExistingButton.Text = 'Load Existing';
            app.PrevCell = uibutton(app.LeftPanel, 'push');
            app.PrevCell.ButtonPushedFcn = createCallbackFcn(app, @PrevCellButtonPushed, true);
            app.PrevCell.Text = 'Prev.';
            app.NextUnapprovedButton = uibutton(app.LeftPanel, 'push');
            app.NextUnapprovedButton.ButtonPushedFcn = createCallbackFcn(app, @NextUnapprovedButtonPushed, true);
            app.NextUnapprovedButton.Text = 'Next Unapproved';
            app.ShowApprovedCellsCheckBox = uicheckbox(app.LeftPanel);
            app.ShowApprovedCellsCheckBox.Text = 'Show Approved Cells';
            app.ShowApprovedCellsCheckBox.Value = true;
            app.UnapproveCurrentCell = uibutton(app.LeftPanel, 'push');
            app.UnapproveCurrentCell.ButtonPushedFcn = createCallbackFcn(app, @UnapproveCurrentCellButtonPushed, true);
            app.UnapproveCurrentCell.Text = 'Unapprove';
            app.CurrentCellAxes = uiaxes(app.LeftPanel);
            title(app.CurrentCellAxes, 'Current Cell')
            app.CurrentCellAxes.XTick = [];
            app.CurrentCellAxes.YTick = [];
            app.CurrentCellAxes.ZTick = [];
            app.CenterPanel = uipanel(app.GridLayout);
            app.MainImageAxes = uiaxes(app.CenterPanel);
            title(app.MainImageAxes, 'Original Image')
            app.MainImageAxes.XTick = [];
            app.MainImageAxes.YTick = [];
            app.ApprovedImageAxes = uiaxes(app.CenterPanel);
            title(app.ApprovedImageAxes, 'Approved Cells')
            app.ApprovedImageAxes.XTick = [];
            app.ApprovedImageAxes.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.SaveDataButton = uibutton(app.RightPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.NumApprovedEditFieldLabel = uilabel(app.RightPanel);
            app.NumApprovedEditFieldLabel.Text = 'Num. Approved';
            app.NumApprovedEditField = uieditfield(app.RightPanel, 'numeric');
            app.NumApprovedEditField.Editable = 'off';
            app.TotalNumCellsEditFieldLabel = uilabel(app.RightPanel);
            app.TotalNumCellsEditFieldLabel.Text = 'Total Num. Cells';
            app.TotalNumCellsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TotalNumCellsEditField.Editable = 'off';
            app.AddNewCellButton = uibutton(app.RightPanel, 'push');
            app.AddNewCellButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewCellButtonPushed, true);
            app.AddNewCellButton.Text = 'Add New Cell';
            app.EnlargeFindCellsResultButton = uibutton(app.RightPanel, 'push');
            app.EnlargeFindCellsResultButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeFindCellsResultButtonPushed, true);
            app.EnlargeFindCellsResultButton.Text = 'Enlarge FindCells Result';
            app.EnlargeApprovedCellsButton = uibutton(app.RightPanel, 'push');
            app.EnlargeApprovedCellsButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeApprovedCellsButtonPushed, true);
            app.EnlargeApprovedCellsButton.Text = 'Enlarge Approved Cells';
            app.GotoCellButton = uibutton(app.RightPanel, 'push');
            app.GotoCellButton.ButtonPushedFcn = createCallbackFcn(app, @GotoCellButtonPushed, true);
            app.GotoCellButton.Text = 'Go to Cell';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellmasker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end